classdef VirtualSensorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VirtualSensorAppUIFigure  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        ImportTab                 matlab.ui.container.Tab
        GridLayout2               matlab.ui.container.GridLayout
        VariablesListBox          matlab.ui.control.ListBox
        VariablesListBoxLabel     matlab.ui.control.Label
        ImportFileButton          matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        ModelTab                  matlab.ui.container.Tab
        GridLayout                matlab.ui.container.GridLayout
        ProgressBar               wt.ProgressBar
        NotusedLabel              matlab.ui.control.Label
        ResponseLabel             matlab.ui.control.Label
        PredictorLabel            matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
        TrainModelButton          matlab.ui.control.Button
        PredictTab                matlab.ui.container.Tab
        GridLayout3               matlab.ui.container.GridLayout
        ModelstatusLabel          matlab.ui.control.Label
        PredictButton             matlab.ui.control.Button
        UIAxes_2                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data % data read from the user-defined file
        responseColor = [0.90,0.91,0.83];
        predictorColor = [0.83,0.91,0.84];
        notUsedColor = [0.91,0.83,0.83];
        model
        prediction
        predictors = {'FuelQuantity', 'OilPressure', 'OilTemperature',...
                      'Altitude', 'ExhaustTemperature', 'FuelFlow',...
                      'FanSpeed', 'WindDirection', 'WindSpeed'}; % used by the model
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed(app, event)
            [file,path] = uigetfile({'*.xlsx','*.csv'},'Choose file');
            fpath = fullfile(path,file);
            if ~isempty(file)
                try
                    app.data = importFlightData(fpath);
                    app.data = app.data(app.data.TrueAirSpeed > 10,:);
                    vars = app.data.Properties.VariableNames;
                    app.VariablesListBox.Items = vars(2:end);
                catch me
                    msgbox("Error: " + me.message)
                end
                app.TrainModelButton.Enable = "on";
            end
        end

        % Value changed function: VariablesListBox
        function VariablesListBoxValueChanged(app, event)
            value = app.VariablesListBox.Value;
            plot(app.UIAxes,app.data.Time,app.data{:,value})
            xlabel(app.UIAxes,"Time");
            ylabel(app.UIAxes,value);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;

            if selectedTab.Title == "Model"

                if ~isempty(app.data)
                    app.UITable.Data = app.data.Properties.VariableNames(2:end)';


                    rowColors = repmat(app.notUsedColor, size(app.UITable.Data, 1), 1);
                    for i = 1:length(app.UITable.Data)
                        if ismember(app.UITable.Data{i}, app.predictors)
                            rowColors(i,:) = app.predictorColor;
                        elseif ismember(app.UITable.Data{i}, 'TrueAirSpeed')
                            rowColors(i,:) = app.responseColor;
                        end
                    end
                    app.UITable.BackgroundColor = rowColors;
                end
            elseif selectedTab.Title == "Predict"
                if isempty(app.model)
                    app.PredictButton.Enable = "off";
                    app.ModelstatusLabel.Text = "Model status: not trained";
                else
                    app.PredictButton.Enable = "on";
                    app.ModelstatusLabel.Text = "Model status: trained";
                end
            end
        end

        % Button pushed function: TrainModelButton
        function TrainModelButtonPushed(app, event)
            if ~isempty(app.data)
                try
                    cleanedData = cleanOilSensors(app.data);
                    app.ProgressBar.Indeterminate = true;
                    app.ProgressBar.startProgress("Training model...");
                    app.model = trainTASModel(cleanedData);
                    app.ProgressBar.finishProgress("Model training finished");
                    app.ProgressBar.Indeterminate = false;
                catch me
                    msgbox("Error: " + me.message)
                end
            end
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            if ~isempty(app.model)
                try 
                    app.prediction = app.model.predictFcn(cleanOilSensors(app.data));
                    hold(app.UIAxes_2,'on')
                    cleanData = cleanOilSensors(app.data);
                    plot(app.UIAxes_2,cleanData.Time,cleanData.TrueAirSpeed)
                    plot(app.UIAxes_2,cleanData.Time,app.prediction)
                    hold(app.UIAxes_2,"off")
                    legend(app.UIAxes_2,"Actual","Predicted")
                catch me
                    msgbox("Something went wrong when predicting response")
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VirtualSensorAppUIFigure = uifigure('Visible', 'off');
            app.VirtualSensorAppUIFigure.Name = 'Virtual Sensor App';
            app.TabGroup = uitabgroup(app.VirtualSensorAppUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Title = 'Import';
            app.GridLayout2 = uigridlayout(app.ImportTab);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ImportFileButton = uibutton(app.GridLayout2, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportFileButton.Text = 'Import File';
            app.VariablesListBoxLabel = uilabel(app.GridLayout2);
            app.VariablesListBoxLabel.Text = 'Variables';
            app.VariablesListBox = uilistbox(app.GridLayout2);
            app.VariablesListBox.Items = {};
            app.VariablesListBox.ValueChangedFcn = createCallbackFcn(app, @VariablesListBoxValueChanged, true);
            app.VariablesListBox.Value = {};
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.GridLayout = uigridlayout(app.ModelTab);
            app.TrainModelButton = uibutton(app.GridLayout, 'push');
            app.TrainModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainModelButtonPushed, true);
            app.TrainModelButton.Enable = 'off';
            app.TrainModelButton.Text = 'Train Model';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Variables'};
            app.UITable.RowName = {};
            app.PredictorLabel = uilabel(app.GridLayout);
            app.PredictorLabel.Text = 'Predictor';
            app.ResponseLabel = uilabel(app.GridLayout);
            app.ResponseLabel.Text = 'Response';
            app.NotusedLabel = uilabel(app.GridLayout);
            app.NotusedLabel.Text = 'Not used';
            app.ProgressBar = wt.ProgressBar(app.GridLayout);
            app.PredictTab = uitab(app.TabGroup);
            app.PredictTab.Title = 'Predict';
            app.GridLayout3 = uigridlayout(app.PredictTab);
            app.UIAxes_2 = uiaxes(app.GridLayout3);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.PredictButton = uibutton(app.GridLayout3, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.ModelstatusLabel = uilabel(app.GridLayout3);
            app.ModelstatusLabel.Text = 'Model status: ';
            % Show the figure after all components are created
            app.VirtualSensorAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VirtualSensorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VirtualSensorAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VirtualSensorAppUIFigure)
        end
    end
end